classdef data_processing_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataProcessorUIFigure  matlab.ui.Figure
        ctrl_image_view        matlab.ui.control.UIAxes
        ctrl_select_folder     matlab.ui.control.Button
        ImagesLabel            matlab.ui.control.Label
        ctrl_image_selector    matlab.ui.control.ListBox
        Panel                  matlab.ui.container.Panel
        XSpinnerLabel          matlab.ui.control.Label
        ctrl_x_spin            matlab.ui.control.Spinner
        YSpinnerLabel          matlab.ui.control.Label
        ctrl_y_spin            matlab.ui.control.Spinner
        WidthSpinnerLabel      matlab.ui.control.Label
        ctrl_w_spin            matlab.ui.control.Spinner
        HeightSpinnerLabel     matlab.ui.control.Label
        ctrl_h_spin            matlab.ui.control.Spinner
        ctrl_process_data      matlab.ui.control.Button
        filematchLabel         matlab.ui.control.Label
        ctrl_matcher           matlab.ui.control.EditField
        DataFolderLabel        matlab.ui.control.Label
        ctrl_data_folder       matlab.ui.control.EditField
        SaveFolderLabel        matlab.ui.control.Label
        ctrl_save_folder       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        start_path = '';        % Description
        image_listing;          % Description
        valid_data = false; % Description
        save_path = ''; % Description
    end
    
    methods (Access = private)
        
        function uifigurepop(app)
            drawnow;
            app.DataProcessorUIFigure.Visible = 'off';
            app.DataProcessorUIFigure.Visible = 'on';
        end 
        
        function show_image(app)
            
            if(~isempty(app.ctrl_image_selector.Value))
                image_index = app.ctrl_image_selector.Value;
            else
                image_index = app.ctrl_image_selector.ItemsData;
            end

            img = imread(fullfile(app.image_listing(1).folder, app.image_listing(image_index(1)).name));
            
            img = insertShape(img, 'Rectangle', [app.ctrl_x_spin.Value, app.ctrl_y_spin.Value, app.ctrl_w_spin.Value-1, app.ctrl_h_spin.Value-1], 'Color', 'r', 'Opacity',1, 'LineWidth',3, 'SmoothEdges', false);
        
            image(app.ctrl_image_view, img);
            
            xlim(app.ctrl_image_view, [1, size(img,2)]);
            ylim(app.ctrl_image_view, [1, size(img,1)]);
        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ctrl_select_folder
        function ctrl_select_folderButtonPushed(app, event)

            image_path = uigetdir(app.start_path, 'Select Folder with Images');
            if(image_path == 0)
                app.valid_data = false;
                return;
            end
            
            app.valid_data = true;
            app.image_listing = dir(strcat(image_path, '/', app.ctrl_matcher.Value));
            
            uifigurepop(app);
            
            app.ctrl_image_selector.Items = {app.image_listing.name}';
            app.ctrl_image_selector.ItemsData = [1:numel(app.image_listing)];
            app.ctrl_image_selector.Value = 1;            
            
            app.start_path = image_path;
            
            app.ctrl_data_folder.Value = image_path;
            
            show_image(app);
            
        end

        % Value changed function: ctrl_image_selector
        function ctrl_image_selectorValueChanged(app, event)
            
            show_image(app);
            
        end

        % Button pushed function: ctrl_process_data
        function ctrl_process_dataButtonPushed(app, event)
            
        
            if(app.valid_data == false)
                return;
            end
            
            img_w = app.ctrl_x_spin.Value:(app.ctrl_x_spin.Value+app.ctrl_w_spin.Value-1); 
            img_h = app.ctrl_y_spin.Value:(app.ctrl_y_spin.Value+app.ctrl_h_spin.Value-1);
            
            tmp_save_path = uigetdir(app.save_path, 'Select Folder to save Processed Images');
            if(tmp_save_path == 0)
                return;
            end
            app.save_path = tmp_save_path;
            
            app.ctrl_save_folder.Value = app.save_path;
            
            uifigurepop(app);
            %pause(0.5);
            
            log_file = fullfile(app.save_path, 'data_processing_log.txt');
            file_id = fopen(log_file, 'w');
            fprintf(file_id, '# X, Y, W, H\n');
            fprintf(file_id, '%d, %d, %d, %d\n\n', img_w(1), img_h(1), numel(img_w),numel(img_h));
            fprintf(file_id, '# file name\n');
                        
            if(~isempty(app.ctrl_image_selector.Value))
                image_index = app.ctrl_image_selector.Value;
            else
                image_index = app.ctrl_image_selector.ItemsData;
            end
            
            wb = waitbar(0.0, ' ', 'Name', strcat('Images:', 32, num2str(length(image_index))));
            wb.Children.Title.Interpreter = 'none';
            
            for idx=1:length(image_index)
                
                %tmp = read_binary_lidar_data(fullfile(app.train_data_path,app.train_dm_files{idx}));
                img = imread(fullfile(app.image_listing(1).folder, app.image_listing(image_index(idx)).name));
%                 display_img = insertShape(img, 'Rectangle', [app.ctrl_x_spin.Value, app.ctrl_y_spin.Value, app.ctrl_w_spin.Value-1, app.ctrl_h_spin.Value-1], 'Color', 'r', 'Opacity',1, 'LineWidth',2, 'SmoothEdges', false);
                
                img = img(img_h, img_w, :);
                       
%                 image(app.ctrl_image_view, display_img);
%                 drawnow;
                
                imwrite(img, fullfile(app.save_path, app.image_listing(image_index(idx)).name));
                fprintf(file_id, '%s\n', app.image_listing(image_index(idx)).name);
                
                waitbar(idx/length(image_index), wb, app.image_listing(image_index(idx)).name);
            end
            
            fclose(file_id);
            
            close(wb);
        end

        % Value changed function: ctrl_h_spin, ctrl_w_spin, 
        % ctrl_x_spin, ctrl_y_spin
        function spinner_changed(app, event)
            if(app.valid_data == true)
                show_image(app);
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataProcessorUIFigure = uifigure('Visible', 'off');
            app.DataProcessorUIFigure.Name = 'Data Processor';
            app.ctrl_image_view = uiaxes(app.DataProcessorUIFigure);
            app.ctrl_image_view.XTick = [];
            app.ctrl_image_view.YTick = [];
            app.ctrl_select_folder = uibutton(app.DataProcessorUIFigure, 'push');
            app.ctrl_select_folder.ButtonPushedFcn = createCallbackFcn(app, @ctrl_select_folderButtonPushed, true);
            app.ctrl_select_folder.Text = 'Select Folder';
            app.ImagesLabel = uilabel(app.DataProcessorUIFigure);
            app.ImagesLabel.Text = 'Images';
            app.ctrl_image_selector = uilistbox(app.DataProcessorUIFigure);
            app.ctrl_image_selector.Items = {};
            app.ctrl_image_selector.Multiselect = 'on';
            app.ctrl_image_selector.ValueChangedFcn = createCallbackFcn(app, @ctrl_image_selectorValueChanged, true);
            app.ctrl_image_selector.Value = {};
            app.Panel = uipanel(app.DataProcessorUIFigure);
            app.XSpinnerLabel = uilabel(app.Panel);
            app.XSpinnerLabel.Text = 'X';
            app.ctrl_x_spin = uispinner(app.Panel);
            app.ctrl_x_spin.Limits = [0 Inf];
            app.ctrl_x_spin.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_x_spin.Value = 140;
            app.YSpinnerLabel = uilabel(app.Panel);
            app.YSpinnerLabel.Text = 'Y';
            app.ctrl_y_spin = uispinner(app.Panel);
            app.ctrl_y_spin.Limits = [0 Inf];
            app.ctrl_y_spin.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_y_spin.Value = 140;
            app.WidthSpinnerLabel = uilabel(app.Panel);
            app.WidthSpinnerLabel.Text = 'Width';
            app.ctrl_w_spin = uispinner(app.Panel);
            app.ctrl_w_spin.Limits = [0 Inf];
            app.ctrl_w_spin.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_w_spin.Value = 256;
            app.HeightSpinnerLabel = uilabel(app.Panel);
            app.HeightSpinnerLabel.Text = 'Height';
            app.ctrl_h_spin = uispinner(app.Panel);
            app.ctrl_h_spin.Limits = [0 Inf];
            app.ctrl_h_spin.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_h_spin.Value = 256;
            app.ctrl_process_data = uibutton(app.DataProcessorUIFigure, 'push');
            app.ctrl_process_data.ButtonPushedFcn = createCallbackFcn(app, @ctrl_process_dataButtonPushed, true);
            app.ctrl_process_data.Text = 'Process Data';
            app.filematchLabel = uilabel(app.DataProcessorUIFigure);
            app.filematchLabel.Text = 'file match';
            app.ctrl_matcher = uieditfield(app.DataProcessorUIFigure, 'text');
            app.ctrl_matcher.Value = '*.png';
            app.DataFolderLabel = uilabel(app.DataProcessorUIFigure);
            app.DataFolderLabel.Text = 'Data Folder';
            app.ctrl_data_folder = uieditfield(app.DataProcessorUIFigure, 'text');
            app.ctrl_data_folder.Editable = 'off';
            app.SaveFolderLabel = uilabel(app.DataProcessorUIFigure);
            app.SaveFolderLabel.Text = 'Save Folder';
            app.ctrl_save_folder = uieditfield(app.DataProcessorUIFigure, 'text');
            app.ctrl_save_folder.Editable = 'off';
            % Show the figure after all components are created
            app.DataProcessorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_processing_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataProcessorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataProcessorUIFigure)
        end
    end
end