classdef reference_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_reference_generator  matlab.ui.Figure
        ctrl_image_view         matlab.ui.control.UIAxes
        BlockSizePanel          matlab.ui.container.Panel
        WidthSpinnerLabel       matlab.ui.control.Label
        ctrl_w_spinner          matlab.ui.control.Spinner
        HeightSpinnerLabel      matlab.ui.control.Label
        ctrl_h_spinner          matlab.ui.control.Spinner
        ctrl_save_button        matlab.ui.control.Button
        ImageSizePanel          matlab.ui.container.Panel
        WidthSpinnerLabel_2     matlab.ui.control.Label
        ctrl_img_w_spinner      matlab.ui.control.Spinner
        HeightSpinnerLabel_2    matlab.ui.control.Label
        ctrl_img_h_spinner      matlab.ui.control.Spinner
        RotationLabel           matlab.ui.control.Label
        ctrl_img_rot            matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        img_width = 512;
        img_height = 512;   % Description
        img % Description
    end
    
    methods (Access = private)
        
        function update_image(app)
            block_w = app.ctrl_w_spinner.Value;
            block_h = app.ctrl_h_spinner.Value;
            
            tmp_img = zeros(app.img_height + block_h, app.img_width + block_w, 3);
            white = 255*ones(block_h, block_w, 3);
            
            color_row = false;
            color_column = false;
    
            for idx = 1:block_h:app.img_height
                color_row = ~color_row;
                color_column = color_row;
        
                for jdx = 1:block_w:app.img_width
                    if (color_column)
                        tmp_img(idx:idx+block_h-1, jdx:jdx+block_w-1, :) = white;
                    end
                    color_column = ~color_column;
                end
            end
            
            c_x = floor((app.img_width - app.ctrl_img_w_spinner.Value)/2):1:floor((app.img_width + app.ctrl_img_w_spinner.Value)/2)-1;
            c_y = floor((app.img_height - app.ctrl_img_h_spinner.Value)/2):1:floor((app.img_height + app.ctrl_img_h_spinner.Value)/2)-1;
                      
            tmp_img = imrotate(tmp_img, app.ctrl_img_rot.Value, 'bicubic', 'loose');
            
            app.img = tmp_img(c_y, c_x, :);
            
            image(app.ctrl_image_view, app.img);
            
            xlim(app.ctrl_image_view, [1, app.ctrl_img_w_spinner.Value]);
            ylim(app.ctrl_image_view, [1, app.ctrl_img_h_spinner.Value]);
            
        end
        
        function uifigurepop(app)
            drawnow;
            app.ui_reference_generator.Visible = 'off';
            app.ui_reference_generator.Visible = 'on';
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_image(app);
        end

        % Value changed function: ctrl_h_spinner, 
        % ctrl_img_h_spinner, ctrl_img_rot, ctrl_img_w_spinner, 
        % ctrl_w_spinner
        function spinner_changed(app, event)

            update_image(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_reference_generator = uifigure('Visible', 'off');
            app.ui_reference_generator.Name = 'UI Figure';
            app.ctrl_image_view = uiaxes(app.ui_reference_generator);
            title(app.ctrl_image_view, '')
            xlabel(app.ctrl_image_view, '')
            ylabel(app.ctrl_image_view, '')
            app.ctrl_image_view.XTick = [];
            app.ctrl_image_view.YTick = [];
            app.BlockSizePanel = uipanel(app.ui_reference_generator);
            app.BlockSizePanel.Title = 'Block Size';
            app.WidthSpinnerLabel = uilabel(app.BlockSizePanel);
            app.WidthSpinnerLabel.Text = 'Width';
            app.ctrl_w_spinner = uispinner(app.BlockSizePanel);
            app.ctrl_w_spinner.Limits = [0 Inf];
            app.ctrl_w_spinner.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_w_spinner.Value = 32;
            app.HeightSpinnerLabel = uilabel(app.BlockSizePanel);
            app.HeightSpinnerLabel.Text = 'Height';
            app.ctrl_h_spinner = uispinner(app.BlockSizePanel);
            app.ctrl_h_spinner.Limits = [0 Inf];
            app.ctrl_h_spinner.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_h_spinner.Value = 32;
            app.ctrl_save_button = uibutton(app.ui_reference_generator, 'push');
            app.ctrl_save_button.Text = 'Save';
            app.ImageSizePanel = uipanel(app.ui_reference_generator);
            app.ImageSizePanel.Title = 'Image Size';
            app.WidthSpinnerLabel_2 = uilabel(app.ImageSizePanel);
            app.WidthSpinnerLabel_2.Text = 'Width';
            app.ctrl_img_w_spinner = uispinner(app.ImageSizePanel);
            app.ctrl_img_w_spinner.Limits = [0 Inf];
            app.ctrl_img_w_spinner.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_img_w_spinner.Value = 256;
            app.HeightSpinnerLabel_2 = uilabel(app.ImageSizePanel);
            app.HeightSpinnerLabel_2.Text = 'Height';
            app.ctrl_img_h_spinner = uispinner(app.ImageSizePanel);
            app.ctrl_img_h_spinner.Limits = [0 Inf];
            app.ctrl_img_h_spinner.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_img_h_spinner.Value = 256;
            app.RotationLabel = uilabel(app.ImageSizePanel);
            app.RotationLabel.Text = 'Rotation';
            app.ctrl_img_rot = uispinner(app.ImageSizePanel);
            app.ctrl_img_rot.Step = 0.01;
            app.ctrl_img_rot.Limits = [-359 359];
            app.ctrl_img_rot.ValueDisplayFormat = '%.2f';
            app.ctrl_img_rot.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            % Show the figure after all components are created
            app.ui_reference_generator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reference_generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_reference_generator)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_reference_generator)
        end
    end
end