classdef reference_generator_2020b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui_reference_generator  matlab.ui.Figure
        BlockSizePanel          matlab.ui.container.Panel
        WidthSpinnerLabel       matlab.ui.control.Label
        ctrl_w_spinner          matlab.ui.control.Spinner
        HeightSpinnerLabel      matlab.ui.control.Label
        ctrl_h_spinner          matlab.ui.control.Spinner
        ctrl_save_button        matlab.ui.control.Button
        ImageSizePanel          matlab.ui.container.Panel
        WidthSpinnerLabel_2     matlab.ui.control.Label
        ctrl_img_w_spinner      matlab.ui.control.Spinner
        HeightSpinnerLabel_2    matlab.ui.control.Label
        ctrl_img_h_spinner      matlab.ui.control.Spinner
        RotationLabel           matlab.ui.control.Label
        ctrl_img_rot            matlab.ui.control.Spinner
        XOffsetLabel            matlab.ui.control.Label
        ctrl_x_offset           matlab.ui.control.Spinner
        YOffsetLabel            matlab.ui.control.Label
        ctrl_y_offset           matlab.ui.control.Spinner
        ScaleLabel              matlab.ui.control.Label
        ctrl_img_scale          matlab.ui.control.Spinner
        ctrl_overlay_button     matlab.ui.control.Button
        PixelRangePanel         matlab.ui.container.Panel
        min_label               matlab.ui.control.Label
        ctrl_px_min             matlab.ui.control.Spinner
        max_label               matlab.ui.control.Label
        ctrl_px_max             matlab.ui.control.Spinner
        ctrl_overlay_button_2   matlab.ui.control.Button
        ctrl_image_view         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img_width = 1000;
        img_height = 1000;  % Description
        img;                % Description
        startpath;          % Description
        overlay_img = [];        % Description
    end
    
    methods (Access = private)
        
        function update_image(app)
            block_w = app.ctrl_w_spinner.Value;
            block_h = app.ctrl_h_spinner.Value;
            
            px_min = app.ctrl_px_min.Value;
            px_max = app.ctrl_px_max.Value;
            
%             padding_h = ceil(ceil((app.img_height+4*block_h)/block_h)*block_h) - app.img_height;
%             padding_w = ceil(ceil((app.img_width+4*block_w)/block_w)*block_w) - app.img_width;
            
            padding_h = ceil(app.img_height+4*block_h + 1) - app.img_height;
            padding_w = ceil(app.img_width+4*block_w + 1) - app.img_width;
            
            
            tmp_img = px_min * ones((app.img_height + padding_h), (app.img_width + padding_w), 3);
%             white = px_max * ones(block_h, block_w, 3);
            
            img_line_h = floor(0:block_h:ceil(app.img_height + padding_h));
            img_line_w = floor(0:block_w:ceil(app.img_width + padding_w));

            color_row = false;
            color_column = false;
    
            for idx = 1:numel(img_line_h)-1
                color_row = ~color_row;
                color_column = color_row;
            
                for jdx=1:numel(img_line_w)-1
                    if (color_column)
                        img_y = (img_line_h(idx)+1:img_line_h(idx+1));
                        img_x = (img_line_w(jdx)+1:img_line_w(jdx+1));
                        tmp_img(img_y, img_x, :) = px_max * ones(numel(img_y), numel(img_x), 3);
                    end
                    color_column = ~color_column;
                end
            end
            
            o_x = app.ctrl_x_offset.Value;
            o_y = app.ctrl_y_offset.Value;
            
%             c_x = app.ctrl_x_offset.Value + (floor((app.img_width - app.ctrl_img_w_spinner.Value)/2):1:(floor((app.img_width + app.ctrl_img_w_spinner.Value)/2)-1));
%             c_y = app.ctrl_y_offset.Value + (floor((app.img_height - app.ctrl_img_h_spinner.Value)/2):1:(floor((app.img_height + app.ctrl_img_h_spinner.Value)/2)-1));
            c_x = app.ctrl_x_offset.Value + padding_w + (1:1:(app.ctrl_img_w_spinner.Value));
            c_y = app.ctrl_y_offset.Value + padding_h + (1:1:(app.ctrl_img_h_spinner.Value));
                        
            tmp_img = imresize(tmp_img, app.ctrl_img_scale.Value, 'nearest');
            
            tmp_img = imrotate(tmp_img, app.ctrl_img_rot.Value, 'bicubic', 'loose');
%             tmp_img = convn(double(tmp_img), ones(3))/9;
            
            app.img = uint8(tmp_img(c_y, c_x, :));
            
            if(~isempty(app.overlay_img))
                app.img(:,:,2) = app.overlay_img(1:app.ctrl_img_h_spinner.Value, 1:app.ctrl_img_w_spinner.Value);                
            end
            
            image(app.ctrl_image_view, app.img);
            
            xlim(app.ctrl_image_view, [1, app.ctrl_img_w_spinner.Value]);
            ylim(app.ctrl_image_view, [1, app.ctrl_img_h_spinner.Value]);
            
        end
        
        function uifigurepop(app)
            drawnow;
            app.ui_reference_generator.Visible = 'off';
            app.ui_reference_generator.Visible = 'on';
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            full_path = mfilename('fullpath');
            [app.startpath,  ~, ~] = fileparts(full_path);
            update_image(app);
        end

        % Value changed function: ctrl_h_spinner, 
        % ctrl_img_h_spinner, ctrl_img_rot, ctrl_img_scale, 
        % ctrl_img_w_spinner, ctrl_px_max, ctrl_px_min, 
        % ctrl_w_spinner, ctrl_x_offset, ctrl_y_offset
        function spinner_changed(app, event)

            update_image(app);            
        end

        % Button pushed function: ctrl_save_button
        function ctrl_save_buttonButtonPushed(app, event)
            file_filter = {'*.png','Image Files';'*.*','All Files' };

            [data_file, data_path] = uiputfile(file_filter, 'Enter Save File Name', app.startpath);
            if(data_path == 0)
                return;
            end
            
            app.startpath = data_path;
            
            imwrite(app.img(:,:,1), fullfile(data_path, data_file));

        end

        % Button pushed function: ctrl_overlay_button
        function ctrl_overlay_buttonButtonPushed(app, event)
            file_filter = {'*.png','Image Files';'*.*','All Files' };

            [data_file, data_path] = uigetfile(file_filter, 'Enter Save File Name', app.startpath);
            if(data_path == 0)
                app.overlay_img = [];
                return;
            end
            app.startpath = data_path;
            
            app.overlay_img = rgb2gray(imread(fullfile(data_path, data_file)));
            
            app.ctrl_img_h_spinner.Value = size(app.overlay_img, 1);
            app.ctrl_img_w_spinner.Value = size(app.overlay_img, 2);
            
            update_image(app);            

        end

        % Button pushed function: ctrl_overlay_button_2
        function ctrl_overlay_button_2ButtonPushed(app, event)
            app.overlay_img = [];
            update_image(app);            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui_reference_generator = uifigure('Visible', 'off');
            app.ui_reference_generator.Name = 'UI Figure';
            app.BlockSizePanel = uipanel(app.ui_reference_generator);
            app.BlockSizePanel.Title = 'Block Size';
            app.WidthSpinnerLabel = uilabel(app.BlockSizePanel);
            app.WidthSpinnerLabel.Text = 'Width';
            app.ctrl_w_spinner = uispinner(app.BlockSizePanel);
            app.ctrl_w_spinner.Step = 0.1;
            app.ctrl_w_spinner.Limits = [0 Inf];
            app.ctrl_w_spinner.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_w_spinner.Value = 10;
            app.HeightSpinnerLabel = uilabel(app.BlockSizePanel);
            app.HeightSpinnerLabel.Text = 'Height';
            app.ctrl_h_spinner = uispinner(app.BlockSizePanel);
            app.ctrl_h_spinner.Step = 0.1;
            app.ctrl_h_spinner.Limits = [0 Inf];
            app.ctrl_h_spinner.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_h_spinner.Value = 10;
            app.ctrl_save_button = uibutton(app.ui_reference_generator, 'push');
            app.ctrl_save_button.ButtonPushedFcn = createCallbackFcn(app, @ctrl_save_buttonButtonPushed, true);
            app.ctrl_save_button.Text = 'Save';
            app.ImageSizePanel = uipanel(app.ui_reference_generator);
            app.ImageSizePanel.Title = 'Image Size';
            app.WidthSpinnerLabel_2 = uilabel(app.ImageSizePanel);
            app.WidthSpinnerLabel_2.Text = 'Width';
            app.ctrl_img_w_spinner = uispinner(app.ImageSizePanel);
            app.ctrl_img_w_spinner.Limits = [0 Inf];
            app.ctrl_img_w_spinner.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_img_w_spinner.Value = 128;
            app.HeightSpinnerLabel_2 = uilabel(app.ImageSizePanel);
            app.HeightSpinnerLabel_2.Text = 'Height';
            app.ctrl_img_h_spinner = uispinner(app.ImageSizePanel);
            app.ctrl_img_h_spinner.Limits = [0 Inf];
            app.ctrl_img_h_spinner.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_img_h_spinner.Value = 128;
            app.RotationLabel = uilabel(app.ImageSizePanel);
            app.RotationLabel.Text = 'Rotation';
            app.ctrl_img_rot = uispinner(app.ImageSizePanel);
            app.ctrl_img_rot.Step = 0.05;
            app.ctrl_img_rot.Limits = [-359 359];
            app.ctrl_img_rot.ValueDisplayFormat = '%.2f';
            app.ctrl_img_rot.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.XOffsetLabel = uilabel(app.ImageSizePanel);
            app.XOffsetLabel.Text = 'X Offset';
            app.ctrl_x_offset = uispinner(app.ImageSizePanel);
            app.ctrl_x_offset.Limits = [0 Inf];
            app.ctrl_x_offset.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.YOffsetLabel = uilabel(app.ImageSizePanel);
            app.YOffsetLabel.Text = 'Y Offset';
            app.ctrl_y_offset = uispinner(app.ImageSizePanel);
            app.ctrl_y_offset.Limits = [0 Inf];
            app.ctrl_y_offset.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ScaleLabel = uilabel(app.ImageSizePanel);
            app.ScaleLabel.Text = 'Scale';
            app.ctrl_img_scale = uispinner(app.ImageSizePanel);
            app.ctrl_img_scale.Step = 0.001;
            app.ctrl_img_scale.Limits = [-5 5];
            app.ctrl_img_scale.ValueDisplayFormat = '%.3f';
            app.ctrl_img_scale.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_img_scale.Value = 1;
            app.ctrl_overlay_button = uibutton(app.ui_reference_generator, 'push');
            app.ctrl_overlay_button.ButtonPushedFcn = createCallbackFcn(app, @ctrl_overlay_buttonButtonPushed, true);
            app.ctrl_overlay_button.Text = 'Load Overlay';
            app.PixelRangePanel = uipanel(app.ui_reference_generator);
            app.PixelRangePanel.Title = 'Pixel Range';
            app.min_label = uilabel(app.PixelRangePanel);
            app.min_label.Text = 'Min';
            app.ctrl_px_min = uispinner(app.PixelRangePanel);
            app.ctrl_px_min.Limits = [0 255];
            app.ctrl_px_min.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.max_label = uilabel(app.PixelRangePanel);
            app.max_label.Text = 'Max';
            app.ctrl_px_max = uispinner(app.PixelRangePanel);
            app.ctrl_px_max.Limits = [0 255];
            app.ctrl_px_max.ValueChangedFcn = createCallbackFcn(app, @spinner_changed, true);
            app.ctrl_px_max.Value = 255;
            app.ctrl_overlay_button_2 = uibutton(app.ui_reference_generator, 'push');
            app.ctrl_overlay_button_2.ButtonPushedFcn = createCallbackFcn(app, @ctrl_overlay_button_2ButtonPushed, true);
            app.ctrl_overlay_button_2.Text = 'Clear Overlay';
            app.ctrl_image_view = uiaxes(app.ui_reference_generator);
            app.ctrl_image_view.XTick = [];
            app.ctrl_image_view.YTick = [];
            % Show the figure after all components are created
            app.ui_reference_generator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reference_generator_2020b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ui_reference_generator)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui_reference_generator)
        end
    end
end